classdef Myotube_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        EditnucleisegmentationPanel     matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        BackButton_6                    matlab.ui.control.Button
        RedoanalysisButton              matlab.ui.control.Button
        SaveexitButton_2                matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        AddnucleiButton                 matlab.ui.control.Button
        RemovenucleiButton              matlab.ui.control.Button
        AdjustthresholdPanel            matlab.ui.container.Panel
        BackButton_7                    matlab.ui.control.Button
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdLabel                  matlab.ui.control.Label
        Slider_5                        matlab.ui.control.Slider
        DoneButton_4                    matlab.ui.control.Button
        AdjustButton_2                  matlab.ui.control.Button
        ImagecontrolsPanel              matlab.ui.container.Panel
        EnlargerightimageButton         matlab.ui.control.Button
        EnlargeleftimageButton          matlab.ui.control.Button
        EditmaskPanel                   matlab.ui.container.Panel
        ThresholdingButton              matlab.ui.control.Button
        BackButton_5                    matlab.ui.control.Button
        ResetmaskButton                 matlab.ui.control.Button
        SaveexitButton                  matlab.ui.control.Button
        RemovejunkButton                matlab.ui.control.Button
        FillholesButton                 matlab.ui.control.Button
        EditareaButton                  matlab.ui.control.Button
        SeparatinglineButton            matlab.ui.control.Button
        EditbranchingpointsPanel        matlab.ui.container.Panel
        FinddiameterButton_2            matlab.ui.control.Button
        MinlengthEditField              matlab.ui.control.NumericEditField
        MinlengthEditFieldLabel         matlab.ui.control.Label
        BackButton_3                    matlab.ui.control.Button
        RedoButton                      matlab.ui.control.Button
        SaveexitButton_3                matlab.ui.control.Button
        AddpointsButton                 matlab.ui.control.Button
        RemovepointsButton              matlab.ui.control.Button
        SpecifyparametersPanel          matlab.ui.container.Panel
        BackButton_2                    matlab.ui.control.Button
        RunButton_2                     matlab.ui.control.Button
        MindistancemEditField           matlab.ui.control.NumericEditField
        MindistancemEditFieldLabel      matlab.ui.control.Label
        NucleusdiametermEditField       matlab.ui.control.NumericEditField
        NucleusdiametermEditFieldLabel  matlab.ui.control.Label
        SelectfunctionButtonGroup       matlab.ui.container.ButtonGroup
        BranchingpointsButton           matlab.ui.control.Button
        ClusternucleiButton             matlab.ui.control.Button
        IndicatenucleiButton            matlab.ui.control.Button
        EditmaskButton                  matlab.ui.control.Button
        AdjustlevelsButton              matlab.ui.control.Button
        AdjustimagelevelsPanel          matlab.ui.container.Panel
        BackButton_4                    matlab.ui.control.Button
        UpperoutEditField               matlab.ui.control.NumericEditField
        LoweroutEditField               matlab.ui.control.NumericEditField
        UpperinEditField                matlab.ui.control.NumericEditField
        LowerinEditField                matlab.ui.control.NumericEditField
        UpperoutLabel                   matlab.ui.control.Label
        LoweroutLabel                   matlab.ui.control.Label
        UpperinLabel                    matlab.ui.control.Label
        LowerinLabel                    matlab.ui.control.Label
        Slider_4                        matlab.ui.control.Slider
        Slider_3                        matlab.ui.control.Slider
        Slider_2                        matlab.ui.control.Slider
        Slider                          matlab.ui.control.Slider
        SaveexitButton_4                matlab.ui.control.Button
        AdjustButton                    matlab.ui.control.Button
        SelectimageButtonGroup          matlab.ui.container.ButtonGroup
        RunButton                       matlab.ui.control.Button
        MyoDButton                      matlab.ui.control.RadioButton
        MyHCButton                      matlab.ui.control.RadioButton
        DAPIButton                      matlab.ui.control.RadioButton
        PixelsizempixelEditField        matlab.ui.control.NumericEditField
        PixelsizempixelEditFieldLabel   matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        MyoDImageLabel                  matlab.ui.control.Label
        SelectFileButton_3              matlab.ui.control.Button
        StatisticsPanel                 matlab.ui.container.Panel
        Stat4EditField                  matlab.ui.control.NumericEditField
        Stat4EditFieldLabel             matlab.ui.control.Label
        Stat3EditField                  matlab.ui.control.NumericEditField
        Stat3EditFieldLabel             matlab.ui.control.Label
        Stat2EditField                  matlab.ui.control.NumericEditField
        Stat2EditFieldLabel             matlab.ui.control.Label
        Stat1EditField                  matlab.ui.control.NumericEditField
        Stat1EditFieldLabel             matlab.ui.control.Label
        SaveanalysisPanel               matlab.ui.container.Panel
        NoButton_2                      matlab.ui.control.Button
        YesButton_2                     matlab.ui.control.Button
        ConfirmmodificationPanel        matlab.ui.container.Panel
        RemoveCheckBox                  matlab.ui.control.CheckBox
        DoneButton_2                    matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        EditField                       matlab.ui.control.EditField
        MyHCImageLabel                  matlab.ui.control.Label
        DAPIImageLabel                  matlab.ui.control.Label
        SelectFileButton_2              matlab.ui.control.Button
        SelectFileButton                matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            global Files
            [FileName,PathName,FilterIndex] = uigetfile({'*.png';'*.jpg';}, ...
                'File Selector');
            app.EditField.Value = FileName;
            
            figure(app.UIFigure) % Refocus window
            if FilterIndex
            
            A = strsplit(FileName,'.');
            FileNameS = [A(1:(end-1))];
            FileNameS = FileNameS{1};

            Files{1} = FileNameS;
            cd(PathName)
            end
        end

        % Button pushed function: SelectFileButton_2
        function SelectFileButton_2Pushed(app, event)
            global Files 
            [FileName,PathName,FilterIndex] = uigetfile({'*.png';'*.jpg';}, ...
                'File Selector');
            app.EditField_2.Value = FileName;
            
            figure(app.UIFigure) % Refocus window
            if FilterIndex
                
            A = strsplit(FileName,'.');
            ExtName = A(end);
            FileNameS = [A(1:(end-1))];
            FileNameS = FileNameS{1};
            MaskName = strcat(FileNameS,'_mask.',ExtName);
            MaskName = MaskName{1};
            
            Files{2} = FileNameS;
            Files{3} = MaskName;
            cd(PathName)
            end
        end

        % Button pushed function: AdjustButton
        function AdjustButtonPushed(app, event)
            global Mod
            Mod = 1;
            uiresume(app.UIFigure);
        end

        % Button pushed function: SaveexitButton_4
        function SaveexitButton_4Pushed(app, event)
            global Mod
            Mod = 2;
            uiresume(app.UIFigure);
        end

        % Button pushed function: SeparatinglineButton
        function SeparatinglineButtonPushed(app, event)
            global Mod
            Mod = 1;
            uiresume(app.UIFigure);
        end

        % Button pushed function: EditareaButton
        function EditareaButtonPushed(app, event)
            global Mod
            Mod = 2;
            uiresume(app.UIFigure);
        end

        % Button pushed function: FillholesButton
        function FillholesButtonPushed(app, event)
            global Mod
            Mod = 3;
            uiresume(app.UIFigure);
        end

        % Button pushed function: RemovejunkButton
        function RemovejunkButtonPushed(app, event)
            global Mod
            Mod = 4;
            uiresume(app.UIFigure);
        end

        % Button pushed function: SaveexitButton
        function SaveexitButtonPushed(app, event)
            global Mod
            Mod = 5;
            uiresume(app.UIFigure);
        end

        % Button pushed function: DoneButton_2
        function DoneButton_2Pushed(app, event)
            global done cont f
            done = 1;
            cont = 1;
            if ishandle(f)
                figure(f)
            end
            uiresume(app.UIFigure);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            global back done f
            back = 1;
            done = 1;
            if ishandle(f)
                figure(f)
            end
            uiresume(app.UIFigure);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            global undo f
            undo = 1;
            if ishandle(f)
                figure(f)
            end
        end

        % Button pushed function: AdjustlevelsButton
        function AdjustlevelsButtonPushed(app, event)
            global Files Adj Mod 
            app.SelectfunctionButtonGroup.Visible = 'off';
            app.SelectimageButtonGroup.Visible = 'on';
            
            % Allow user to select image
            uiwait(app.UIFigure);
            if app.DAPIButton.Value
                file = Files{1};
            elseif app.MyHCButton.Value
                file = Files{2};
            elseif app.MyoDButton.Value
                file = Files{4};
            end
            
            app.SelectimageButtonGroup.Visible = 'off';
            app.AdjustimagelevelsPanel.Visible = 'on';
            
            img = imread([file '.png']);
            
            % Level adjustment
            limits_in = [app.LowerinEditField.Value, ...
                app.UpperinEditField.Value];
            limits_out = [app.LoweroutEditField.Value, ...
                app.UpperoutEditField.Value];
            im_tmp = imadjust(img,limits_in,limits_out);
            
            % Images
            app.RightPanel.AutoResizeChildren = 'off';
            h(1) = subplot(1,2,1,'Parent',app.RightPanel);
            h(2) = subplot(1,2,2,'Parent',app.RightPanel);
            
            imshow(img,[],'Parent',h(1))
            title(h(1),'Original image')
            
            imshow(im_tmp,'Parent',h(2))
            title(h(2),'Adjusted image')
            linkaxes(h)
            
            % Allow user to choose levels
            app.RightPanel.Title = 'Adjust image levels or save image';
            Adj = 1;
            app.ImagecontrolsPanel.Visible = 'on';
            while Adj
                uiwait(app.UIFigure);
            switch Mod
                case 1 % Adjust levels
                    limits_in = [app.LowerinEditField.Value, ...
                        app.UpperinEditField.Value];
                    limits_out = [app.LoweroutEditField.Value, ...
                        app.UpperoutEditField.Value];
                    im_tmp = imadjust(img,limits_in,limits_out);
                    
                    imshow(im_tmp,'Parent',h(2))
                    title(h(2),'Adjusted image')
                    linkaxes(h)
                case 2 % Done
                    imwrite(im_tmp,[file '_Adjusted.png'],'png')
                    Adj = 0;
                case 3 % Back
                    Adj = 0;
                case 100 % Enlarge left image
                    figure(3)
                    imshow(img,[])
                    title('Original image')
                case 101 % Enlarge right image
                    figure(4)
                    imshow(im_tmp,[])
                    title('Adjusted image')
            end
            Mod = 0;
            end
            
            app.ImagecontrolsPanel.Visible = 'off';
            app.AdjustimagelevelsPanel.Visible = 'off';
            app.SelectfunctionButtonGroup.Visible = 'on';
            app.RightPanel.Title = 'Select function or file';
        end

        % Button pushed function: EditmaskButton
        function EditmaskButtonPushed(app, event)
            global Files cont back done Adj Mod undo f
            app.SelectfunctionButtonGroup.Visible = 'off';
            app.AdjustthresholdPanel.Visible = 'on';
            
            img = imread([Files{2} '_Adjusted.png']);
            app.RightPanel.AutoResizeChildren = 'off';
            h(1) = subplot(1,2,1,'Parent',app.RightPanel);
            h(2) = subplot(1,2,2,'Parent',app.RightPanel);
            
            % If no mask exists, create new one
            skip = 0;
            if ~exist(Files{3},'file')
                mask = imbinarize(img(:,:,1),app.ThresholdEditField.Value);
                        
                imshow(img,[],'Parent',h(1))
                title(h(1),'Adjusted image')
                
                imshow(mask,'Parent',h(2))
                title(h(2),'Binary image')
                linkaxes(h)
                
                % Allow user to set threshold
                app.RightPanel.Title = 'Adjust threshold or continue to editing';
                Adj = 1;
                app.ImagecontrolsPanel.Visible = 'on';
                while Adj
                    uiwait(app.UIFigure);
                    switch Mod
                        case 1 % Adjust threshold
                            mask = imbinarize(img(:,:,1),app.ThresholdEditField.Value);
                            imshow(mask,'Parent',h(2))
                            title(h(2),'Binary image')
                            linkaxes(h)
                        case 2 % Done
                            Adj = 0;
                        case 3 % Back
                            Adj = 0;
                            skip = 1;
                        case 100 % Enlarge left image
                            figure(3)
                            imshow(img,[])
                            title('Adjusted image')
                        case 101 % Enlarge right image
                            figure(4)
                            imshow(mask)
                            title('Binary image')
                    end
                    Mod = 0;
                end
                app.ImagecontrolsPanel.Visible = 'off';
            else 
                % Restore binary mask
                img_mask = imread(Files{3});
                img_mask = rgb2gray(img_mask);
                img_mask = imcomplement(img_mask);
                mask = logical(img_mask);
            end
            app.AdjustthresholdPanel.Visible = 'off';
            n = max(max(bwlabel(mask,8))); % Number of objects
            
            if ~skip % User did not abort thresholding
            % Show adjusted image and mask
            imshow(img,[],'Parent',h(1))
            title(h(1),'Adjusted image')
            
            imshow(mask,'Parent',h(2))
            title(h(2),'Mask')
            linkaxes(h)
            
            % Allow user to edit mask
            app.Stat1EditFieldLabel.Text = '# of Objects';
            app.Stat1EditField.Value = n;
            app.Stat2EditField.Visible = 'off';
            app.Stat3EditField.Visible = 'off';
            app.Stat4EditField.Visible = 'off';
            app.Stat2EditFieldLabel.Visible = 'off';
            app.Stat3EditFieldLabel.Visible = 'off';
            app.Stat4EditFieldLabel.Visible = 'off';
            app.StatisticsPanel.Visible ='on';
            mask_adj = mask;
            Adj = 1;
            done = 0;
            cont = 0;
            back = 0;
            undo = 0;
            while Adj
                app.RightPanel.Title = 'Select function to edit mask or finish mask creation';
                app.EditmaskPanel.Visible = 'on';
                app.ImagecontrolsPanel.Visible = 'on';
                uiwait(app.UIFigure);
                switch Mod
                    case 1 % Separating line
                        app.EditmaskPanel.Visible = 'off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        app.RemoveCheckBox.Value = 1; % Remove = default
                        app.ConfirmmodificationPanel.Visible = 'on';
                        app.RightPanel.Title = 'Draw line to join or separate myotubes';
                        f = figure('DeleteFcn',@figDelete); % Makes sure loop ends if figure is closed
                        imshow(mask_adj)
                        i = 1;
                        while ~done
                            if ~isempty(app.UIFigure) % Prevent endless loop if app is closed
                            % Have user draw on image
                            ROI(i) = drawfreehand(gca,'Closed',false);
                            drawnow
                            if undo
                                delete(ROI(end-1:end));
                                ROI(end-1:end) = [];
                                undo = 0;
                            end
                            i = length(ROI) + 1;
                            end
                        end
                        delete(ROI(end)); % Remove last drawn line
                        ROI(end) = [];
                        app.ConfirmmodificationPanel.Visible = 'off';
                        if cont % Put lines on image
                            for i = 1:length(ROI)
                            freehand_pos = ROI(i).Position;
                            coordinates = reshape(freehand_pos',length(freehand_pos)*2,[]); % Arrange coordinates
                            tmp = zeros(size(mask_adj)); % Create empty mask
                            tmp = insertShape(tmp,'Line',coordinates','LineWidth',5); % Add line
                            mask_adj(find(tmp(:,:,2))) = ~app.RemoveCheckBox.Value; % Burn line in mask
                            cont = 0;
                            end
                        elseif back % Return without doing anything
                            back = 0;
                        end
                        n = max(max(bwlabel(mask_adj,8)));
                        app.Stat1EditField.Value = n;

                        clear ROI
                        imshow(mask_adj,'Parent',h(2))
                        title(h(2),'Mask')
                        linkaxes(h)
                        if ishandle(f)
                        close(f)
                        end
                        done = 0;
                        undo = 0;
                        
                    case 2 % Edit area
                        app.EditmaskPanel.Visible = 'off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        f = figure('DeleteFcn',@figDelete);
                        imshow(mask_adj)
                        app.RemoveCheckBox.Value = 1; % Remove = default
                        app.ConfirmmodificationPanel.Visible = 'on';
                        app.RightPanel.Title = 'Draw to mark area for in- or exclusion';
                        i = 1;
                        while ~done
                            if ~isempty(app.UIFigure) % Prevent endless loop if app is closed
                                % Have user draw on image
                                ROI(i) = drawfreehand(gca);
                                drawnow
                                if undo
                                    delete(ROI(end-1:end)); % Remove 2 last drawn lines
                                    ROI(end-1:end) = [];
                                    undo = 0;
                                end
                                i = length(ROI) + 1;
                            end
                        end
                        delete(ROI(end)); % Remove last drawn line
                        ROI(end) = [];
                        app.ConfirmmodificationPanel.Visible = 'off';
                        
                        if cont % Burn area in image
                            for i = 1:length(ROI)
                            freehand_pos = ROI(i).Position;
                            freehand_mask = poly2mask(freehand_pos(:,1), freehand_pos(:,2), ...
                                size(mask_adj,1), size(mask_adj,2) ); % Create area mask
                            mask_adj(freehand_mask) = ~app.RemoveCheckBox.Value; % Burn area in mask
                            cont = 0;
                            end
                        elseif back % Return without doing anything
                            back = 0;
                        end
                        
                        n = max(max(bwlabel(mask_adj,8)));
                        app.Stat1EditField.Value = n;
                        clear ROI
                        imshow(mask_adj,'Parent',h(2))
                        title(h(2),'Mask')
                        linkaxes(h)
                        if ishandle(f)
                        close(f)
                        end
                        done = 0;
                        undo = 0;
                        
                    case 3 % Fill holes
                        app.EditmaskPanel.Visible = 'off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        % Fill holes in image
                        mask_tmp = imfill(mask_adj,'holes');
                        imshow(mask_tmp,'Parent',h(2))
                        title(h(2),'Mask')
                        linkaxes(h)
                        n = max(max(bwlabel(mask_tmp,8)));
                        app.Stat1EditField.Value = n;
                        app.RemoveCheckBox.Visible = 'off';
                        app.UndoButton.Visible = 'off';
                        app.DoneButton_2.Text = 'Yes';
                        app.BackButton.Text = 'No';
                        app.ConfirmmodificationPanel.Visible = 'on';
                        app.RightPanel.Title = 'Confirm hole filling operation?';
                        uiwait(app.UIFigure);
                        % Allow user to confirm hole filling or to undo
                        if cont
                            mask_adj = mask_tmp; % Set new mask
                            cont = 0;
                        elseif back  
                            % Do not modify mask
                            back = 0;
                        end
                        done = 0;
                        app.RemoveCheckBox.Visible = 'on';
                        app.ConfirmmodificationPanel.Visible = 'off';
                        app.UndoButton.Visible = 'on';
                        app.DoneButton_2.Text = 'Done';
                        app.BackButton.Text = 'Back';
                        imshow(mask_adj,'Parent',h(2))
                        title(h(2),'Mask')
                        linkaxes(h)
                    case 4 % Remove junk
                        app.EditmaskPanel.Visible = 'off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        % Remove junk
                        mask_tmp = bwareaopen(mask_adj,1000,4);
                        imshow(mask_tmp,'Parent',h(2))
                        title(h(2),'Mask')
                        linkaxes(h)
                        n = max(max(bwlabel(mask_tmp,8)));
                        app.Stat1EditField.Value = n;
                        app.RemoveCheckBox.Visible = 'off';
                        app.UndoButton.Visible = 'off';
                        app.DoneButton_2.Text = 'Yes';
                        app.BackButton.Text = 'No';
                        app.ConfirmmodificationPanel.Visible = 'on';
                        app.RightPanel.Title = 'Confirm junk removal?';
                        uiwait(app.UIFigure);
                        % Allow user to confirm junk removal or to undo
                        if cont
                            mask_adj = mask_tmp; % Set new mask
                            cont = 0;
                        elseif back  
                            % Do not modify mask
                            back = 0;
                        end
                        done = 0;
                        app.RemoveCheckBox.Visible = 'on';
                        app.ConfirmmodificationPanel.Visible = 'off';
                        app.UndoButton.Visible = 'on';
                        app.DoneButton_2.Text = 'Done';
                        app.BackButton.Text = 'Back';
                        imshow(mask_adj,'Parent',h(2))
                        title(h(2),'Mask')
                        linkaxes(h)
                    case 5 % Done
                        tmp = bwareaopen(mask_adj,1000,4);
                        if tmp == mask_adj % Check if remove junk makes any difference
                            Adj = 0;
                            mask = mask_adj;
                            label = bwlabel(mask,4);
                            RGB_label = label2rgb(label,'jet',[1,1,1]);
                            imwrite(RGB_label,Files{3},'png')
                        else % Remind user if remove junk makes a difference
                            selection = uiconfirm(app.UIFigure,'Continue without removing junk?', ...
                                'Remove junk reminder','Icon','warning','Options',{'Continue','Cancel'},...
                                'DefaultOption',2,'CancelOption',2);
                            if strcmp(selection,'Continue') % User wants to continue
                                Adj = 0;
                                mask = mask_adj;
                                label = bwlabel(mask,4);
                                RGB_label = label2rgb(label,'jet',[1,1,1]);
                                imwrite(RGB_label,Files{3},'png')
                            else % User cancelled, do nothing
                            end
                        end
                    case 6 % Reset Mask
                        mask_adj = mask;
                        n = max(max(bwlabel(mask,8)));
                        app.Stat1EditField.Value = n;
                        imshow(mask_adj,'Parent',h(2))
                        title(h(2),'Mask')
                        linkaxes(h)
                    case 7 % Back
                        Adj = 0;
                    case 8 % Redo thresholding
                        app.EditmaskPanel.Visible = 'off';
                        app.StatisticsPanel.Visible ='off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        
                        app.AdjustthresholdPanel.Visible = 'on';
                        mask_tmp = imbinarize(img(:,:,1),app.ThresholdEditField.Value);
                        
                        imshow(img,[],'Parent',h(1))
                        title(h(1),'Adjusted image')
                        
                        imshow(mask_tmp,'Parent',h(2))
                        title(h(2),'Binary image')
                        linkaxes(h)
                        
                        % Allow user to set threshold
                        app.RightPanel.Title = 'Adjust threshold or continue to editing';
                        tmp = 1;
                        while tmp
                            uiwait(app.UIFigure);
                        switch Mod
                            case 1 % Adjust threshold
                                mask_tmp = imbinarize(img(:,:,1),app.ThresholdEditField.Value);
                                
                                imshow(mask_tmp,'Parent',h(2))
                                title(h(2),'Binary image')
                                linkaxes(h)
                            case 2 % Done
                                tmp = 0;
                                mask_adj = mask_tmp;
                            case 3 % Back
                                tmp = 0;
                                imshow(mask_adj,'Parent',h(2))
                                title(h(2),'Binary image')
                                linkaxes(h)
                        end
                        Mod = 0;
                        end
                        app.AdjustthresholdPanel.Visible = 'off';
                        app.StatisticsPanel.Visible = 'on';
                        n = max(max(bwlabel(mask_adj,8)));
                        app.Stat1EditField.Value = n;
                    case 100 % Enlarge left image
                        figure(3)
                        imshow(img,[])
                        title('Adjusted image')
                    case 101 % Enlarge right image
                        figure(4)
                        imshow(mask_adj,[])
                        title('Mask')
                end
                Mod = 0;
            end
            end
            app.ImagecontrolsPanel.Visible = 'off';
            app.StatisticsPanel.Visible ='off';
            app.Stat2EditField.Visible = 'on';
            app.Stat3EditField.Visible = 'on';
            app.Stat4EditField.Visible = 'on';
            app.Stat2EditFieldLabel.Visible = 'on';
            app.Stat3EditFieldLabel.Visible = 'on';
            app.Stat4EditFieldLabel.Visible = 'on';
            app.EditmaskPanel.Visible = 'off';
            app.SelectfunctionButtonGroup.Visible = 'on';
            app.RightPanel.Title = 'Select function or file';
        end

        % Button pushed function: ResetmaskButton
        function ResetmaskButtonPushed(app, event)
            global Mod
            Mod = 6;
            uiresume(app.UIFigure);
        end

        % Button pushed function: IndicatenucleiButton
        function IndicatenucleiButtonPushed(app, event)
            global Files Adj Mod done cont back undo f
            app.SelectfunctionButtonGroup.Visible = 'off';
            app.PixelsizempixelEditField.Enable = 'on';
            pix_size_org = app.PixelsizempixelEditField.Value;
            
            if find(app.EditField_3.Value) > 0
                MyoD = 1;
            else 
                MyoD = 0;
            end
            
            img1 = imread([Files{1} '_Adjusted.png']);
            img2 = imread([Files{2} '_Adjusted.png']);
            if MyoD
                img3 = imread([Files{4} '_Adjusted.png']);
                img_mix(:,:,1) = rgb2gray(img2);
                img_mix(:,:,2) = rgb2gray(img3);
                img_mix(:,:,3) = rgb2gray(img1);
                nuc_img = imfuse(img1,img3,'blend','Scaling','independent');
                
                Id2 = rgb2gray(img3);
                Id2 = medfilt2(Id2);
                
                ImBinary2 = imbinarize(Id2,0.05);
                ImBinary2 = bwareaopen(ImBinary2,50); 
            else
                nuc_img = img1;
                img_mix = imfuse(img1,img2,'blend','Scaling','independent');
            end
            img_mask = imread(Files{3});
            
            % Preprocessing
            Id = rgb2gray(img1);
            Id = medfilt2(Id);
            
            % Restore binary mask
            img_mask = rgb2gray(img_mask);
            img_mask = imcomplement(img_mask);
            mask = logical(img_mask);
            mask = cat(3,mask,mask,mask);
            img_mix(~mask) = 0;
            
            if exist([Files{1} '_output.xlsx'],'file')
                redo = 0;
                % Read existing data
                inputmatrix = readmatrix([Files{1} '_output.xlsx'],'Sheet',2,'Range','A2');
                pix_size = readmatrix([Files{1} '_output.xlsx'],'Sheet',1,'Range','B1:B1');
                app.PixelsizempixelEditField.Value = pix_size;
            else
                redo = 1;
            end
            
            if redo == 1 % Do analysis from the beginning
                % Ask user for pixel size to use
                prompt = 'Enter pixel size in µm:';
                dlgtitle = 'Pixel size input';
                definput = {num2str(app.PixelsizempixelEditField.Value)};
                answer = inputdlg(prompt,dlgtitle,1,definput);
                figure(app.UIFigure) % Refocus window
                if isempty(answer) % User cancelled
                    skip = 1;
                else
                    skip = 0;
                    pix_size = str2num(answer{1});
                    app.PixelsizempixelEditField.Value = pix_size;
                    
                    % Detection
                    ImBinary = imbinarize(Id,0.05);
                    ImBinary = bwareaopen(ImBinary,50);
                    
                    % Circular filter
                    radius = 5.136; % Average radius of nucleus
                    H = fspecial('disk',round(radius/pix_size));
                    FiltImg = imfilter(double(Id),H);
                    circ = watershed(-FiltImg);
                    
                    % Add centroids
                    circ(~ImBinary) = 0;
                    regc = regionprops(circ,'centroid');
                    centroids = cat(1,regc.Centroid);
                    centroids = round(rmmissing(centroids));
                    
                    % MyoD+ nuclei check
                    if MyoD
                        sat_check = false(length(centroids),1);
                        for i = 1:length(centroids) % Find nuclei in mask
                            sat_check(i) = ImBinary2(round(centroids(i,2)),round(centroids(i,1)));
                        end
                    end
                    
                    % Find nuclei in tubes
                    coords_mask = false(length(centroids),1);
                    for i = 1:length(centroids)
                        coords_mask(i) = mask(centroids(i,2),centroids(i,1));
                    end
                end
                
            else % Use existing data
                skip = 0;
                centroids = inputmatrix(:,1:2);
                coords_mask = logical(inputmatrix(:,3));
                if MyoD
                    if ~isnan(inputmatrix(1,4))
                        sat_check = logical(inputmatrix(:,4));
                    else
                        sat_check = false(length(centroids),1);
                        for i = 1:length(centroids) % Find nuclei in mask
                            sat_check(i) = ImBinary2(round(centroids(i,2)),round(centroids(i,1)));
                        end
                    end
                end
            end
            
            if ~skip % User did not cancel
                % Show on image
                app.RightPanel.AutoResizeChildren = 'off';
                h(1) = subplot(1,2,1,'Parent',app.RightPanel);
                h(2) = subplot(1,2,2,'Parent',app.RightPanel);
                
                imshow(nuc_img,[],'Parent',h(1))
                hold(h(1),'on');
                if MyoD
                    plot(centroids(~sat_check,1),centroids(~sat_check,2),'y*','Parent',h(1))
                    plot(centroids(sat_check,1),centroids(sat_check,2),'r*','Parent',h(1))
                    legend(h(1),'Nucleus','MyoD+ nucleus')
                else
                    plot(centroids(:,1),centroids(:,2),'y*','Parent',h(1))
                end
                hold(h(1),'off');
                title(h(1),'DAPI Image')
                
                imshow(img_mix,[],'Parent',h(2))
                hold(h(2),'on');
                if MyoD
                    plot(centroids(coords_mask & ~sat_check,1), ...
                        centroids(coords_mask & ~sat_check,2),'y*','Parent',h(2))
                    plot(centroids(coords_mask & sat_check,1), ...
                        centroids(coords_mask & sat_check,2),'r*','Parent',h(2))
                    legend(h(2),'Nucleus','MyoD+ nucleus')
                else
                    plot(centroids(coords_mask,1), ...
                        centroids(coords_mask,2),'y*','Parent',h(2))
                end
                hold(h(2),'off');
                title(h(2),'Adjusted image with mask')
                linkaxes(h)
                
                % Statistics
                app.Stat1EditFieldLabel.Text = 'Total nuclei';
                app.Stat2EditFieldLabel.Text = 'Nuclei in tubes';
                app.Stat3EditFieldLabel.Text = 'Fusion index';
                
                app.Stat1EditField.Value = length(centroids);
                app.Stat2EditField.Value = length(centroids(coords_mask,1));
                app.Stat3EditField.Value = length(centroids(coords_mask,1))/length(centroids);
                
                if MyoD
                    app.Stat4EditFieldLabel.Text = 'MyoD+ nuclei';
                    app.Stat4EditField.Value = length(find(sat_check));
                    
                    sat_tmp = sat_check;
                else
                    app.Stat4EditField.Visible = 'off';
                    app.Stat4EditFieldLabel.Visible = 'off';
                end
                app.StatisticsPanel.Visible ='on';
                
                % Allow editing of segmentation
                coords_tmp = centroids;
                coords_mask_tmp = coords_mask;
                Adj = 1;
            else
                Adj = 0;
            end
            done = 0;
            back = 0;
            undo = 0;
            while Adj
                app.EditnucleisegmentationPanel.Visible = 'on';
                app.ImagecontrolsPanel.Visible = 'on';
                app.RightPanel.Title = 'Select function to edit segmentation';
                uiwait(app.UIFigure);
                switch Mod
                    case 1 % Remove nuclei
                        app.EditnucleisegmentationPanel.Visible = 'off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        f = figure('DeleteFcn',@figDelete);
                        if strcmp(app.Switch.Value,'Left') % Remove on left image
                            imshow(nuc_img,[])
                            hold on
                            plot(coords_tmp(:,1),coords_tmp(:,2),'y*')
                            hold off
                        else % Remove on right image
                            imshow(img_mix,[])
                            hold on
                            plot(coords_tmp(~coords_mask_tmp,1), ...
                                coords_tmp(~coords_mask_tmp,2),'y*');
                            plot(coords_tmp(coords_mask_tmp,1), ...
                                coords_tmp(coords_mask_tmp,2),'r*');
                            hold off
                        end
                        app.RemoveCheckBox.Visible = 'off';
                        app.ConfirmmodificationPanel.Visible = 'on';
                        app.RightPanel.Title = 'Draw to mark area for nuclei removal';
                        i = 1;
                        while ~done
                            if ~isempty(app.UIFigure) % Prevent endless loop if app is closed
                            % Have user draw on image
                            ROI(i) = drawfreehand(gca);
                            drawnow
                            if undo
                                delete(ROI(end-1:end)); % Remove 2 last drawn lines
                                ROI(end-1:end) = [];
                                undo = 0;
                            end
                            i = length(ROI) + 1;
                            end
                        end
                        delete(ROI(end)); % Remove last drawn line
                        ROI(end) = [];
                        app.RemoveCheckBox.Visible = 'on';
                        app.ConfirmmodificationPanel.Visible = 'off';
                        
                        if cont % Burn area in image
                            for i = 1:length(ROI)
                            freehand_pos = ROI(i).Position;
                            freehand_mask = poly2mask(freehand_pos(:,1), freehand_pos(:,2), ...
                                size(nuc_img,1), size(nuc_img,2) ); % Create area mask
                            check = false(length(coords_tmp),1);
                                for j = 1:length(coords_tmp) % Find nuclei in mask
                                    check(j) = freehand_mask(coords_tmp(j,2),coords_tmp(j,1));
                                end
                                % Remove nuclei found in mask
                                if MyoD
                                    sat_tmp(check) = []; 
                                end
                                coords_mask_tmp(check) = [];
                                check = cat(2,check',check');
                                coords_tmp(check) = [];
                                coords_tmp = reshape(coords_tmp,[],2);
                                cont = 0;
                            end
                            
                            % Images
                            imshow(nuc_img,[],'Parent',h(1))
                            hold(h(1),'on');
                            if MyoD
                                plot(coords_tmp(~sat_tmp,1), ...
                                    coords_tmp(~sat_tmp,2),'y*','Parent',h(1))
                                plot(coords_tmp(sat_tmp,1), ...
                                    coords_tmp(sat_tmp,2),'r*','Parent',h(1))
                                legend(h(1),'Nucleus','MyoD+ nucleus')
                            else
                                plot(coords_tmp(:,1),coords_tmp(:,2),'y*','Parent',h(1))
                            end
                            hold(h(1),'off');
                            title(h(1),'DAPI Image')
                            
                            imshow(img_mix,[],'Parent',h(2))
                            hold(h(2),'on');
                            if MyoD
                                plot(coords_tmp(coords_mask_tmp & ~sat_tmp,1), ...
                                    coords_tmp(coords_mask_tmp & ~sat_tmp,2),'y*','Parent',h(2))
                                plot(coords_tmp(coords_mask_tmp & sat_tmp,1), ...
                                    coords_tmp(coords_mask_tmp & sat_tmp,2),'r*','Parent',h(2))
                                legend(h(2),'Nucleus','MyoD+ nucleus')
                            else
                                plot(coords_tmp(coords_mask_tmp,1), ...
                                    coords_tmp(coords_mask_tmp,2),'y*','Parent',h(2))
                            end
                            hold(h(2),'off');
                            title(h(2),'Adjusted image with mask')
                            linkaxes(h)
                            
                            % Statistics
                            app.Stat1EditField.Value = length(coords_tmp);
                            app.Stat2EditField.Value = length(coords_tmp(coords_mask_tmp,1));
                            app.Stat3EditField.Value = length(coords_tmp(coords_mask_tmp,1))/length(coords_tmp);
                            if MyoD
                                app.Stat4EditField.Value = length(find(sat_tmp));
                            end
                        elseif back % Return without doing anything
                            back = 0;
                        end
                        
                        clear ROI
                        if ishandle(f)
                        close(f)
                        end
                        done = 0;
                        undo = 0;
                        
                    case 2 % Add nuclei
                        app.EditnucleisegmentationPanel.Visible = 'off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        
                        figure(1)
                        if strcmp(app.Switch.Value,'Left') % Indicate on left image
                            imshow(nuc_img,[])
                            hold on
                            plot(coords_tmp(:,1),coords_tmp(:,2),'y*')
                            hold off
                        else % Indicate on right image
                            imshow(img_mix,[])
                            hold on
                            plot(coords_tmp(~coords_mask_tmp,1), ...
                                coords_tmp(~coords_mask_tmp,2),'y*');
                            plot(coords_tmp(coords_mask_tmp,1), ...
                                coords_tmp(coords_mask_tmp,2),'r*');
                            hold off
                        end
                        
                        % Allow user to indicate extra nuclei
                        app.RightPanel.Title = 'Click to add nucleus centroids: backspace to remove last, enter to finish';
                        try
                            [coords_extra(:,1),coords_extra(:,2),~] = impixel;
                            skip = 0;
                        catch ME
                            if strcmp(ME.identifier,'images:getpts:interruptedMouseSelection')
                                skip = 1;
                            else
                                rethrow(ME)
                            end
                        end
                        
                        if ~skip % Does not execute if user closes figure window
                            coords_tmp = cat(1,coords_tmp,coords_extra); % Add to existing set
                            clear coords_extra
                            
                            % Check for errors and find nuclei in mask
                            coords_mask_tmp = false(length(coords_tmp),1);
                            error_tmp = false(length(coords_tmp),1);
                            if MyoD
                                sat_tmp = false(length(coords_tmp),1);
                            end
                            for i = 1:length(coords_tmp)
                                try
                                    if MyoD
                                        sat_tmp(i) = ImBinary2(round(coords_tmp(i,2)),round(coords_tmp(i,1)));
                                    end
                                    coords_mask_tmp(i) = mask(coords_tmp(i,2),coords_tmp(i,1));
                                catch ME
                                    error_tmp(i) = 1;
                                end
                            end
                            
                            % Remove errors
                            coords_tmp(error_tmp,:) = [];
                            coords_tmp = reshape(coords_tmp,[],2);
                            coords_mask_tmp(error_tmp,:) = [];
                            if MyoD
                                sat_tmp(error_tmp) = [];
                            end
                            
                            % Images
                            imshow(nuc_img,[],'Parent',h(1))
                            hold(h(1),'on');
                            if MyoD
                                plot(coords_tmp(~sat_tmp,1), ...
                                    coords_tmp(~sat_tmp,2),'y*','Parent',h(1))
                                plot(coords_tmp(sat_tmp,1), ...
                                    coords_tmp(sat_tmp,2),'r*','Parent',h(1))
                                legend(h(1),'Nucleus','MyoD+ nucleus')
                            else
                                plot(coords_tmp(:,1),coords_tmp(:,2),'y*','Parent',h(1))
                            end
                            hold(h(1),'off');
                            title(h(1),'DAPI Image') 
                            
                            imshow(img_mix,[],'Parent',h(2))
                            hold(h(2),'on');
                            if MyoD
                                plot(coords_tmp(coords_mask_tmp & ~sat_tmp,1), ...
                                    coords_tmp(coords_mask_tmp & ~sat_tmp,2),'y*','Parent',h(2))
                                plot(coords_tmp(coords_mask_tmp & sat_tmp,1), ...
                                    coords_tmp(coords_mask_tmp & sat_tmp,2),'r*','Parent',h(2))
                                legend(h(2),'Nucleus','MyoD+ nucleus')
                            else
                                plot(coords_tmp(coords_mask_tmp,1), ...
                                    coords_tmp(coords_mask_tmp,2),'y*','Parent',h(2))
                            end
                            hold(h(2),'off');
                            title(h(2),'Adjusted image with mask')
                            linkaxes(h)
                            
                            app.Stat1EditField.Value = length(coords_tmp);
                            app.Stat2EditField.Value = length(coords_tmp(coords_mask_tmp,1));
                            app.Stat3EditField.Value = length(coords_tmp(coords_mask_tmp,1))/length(coords_tmp);
                            if MyoD
                                app.Stat4EditField.Value = length(find(sat_tmp));
                            end
                            
                            close(1)
                        else
                        end
                        app.EditnucleisegmentationPanel.Visible = 'on';
                        
                    case 3 % Reset analysis
                        app.EditnucleisegmentationPanel.Visible = 'off';
                        
                        coords_tmp = centroids;
                        coords_mask_tmp = coords_mask;
                        if MyoD
                            sat_tmp = sat_check;
                        end
                        
                        imshow(nuc_img,[],'Parent',h(1))
                        hold(h(1),'on');
                        if MyoD
                            plot(coords_tmp(~sat_tmp,1), ...
                                coords_tmp(~sat_tmp,2),'y*','Parent',h(1))
                            plot(coords_tmp(sat_tmp,1), ...
                                coords_tmp(sat_tmp,2),'r*','Parent',h(1))
                            legend(h(1),'Nucleus','MyoD+ nucleus')
                        else
                            plot(coords_tmp(:,1),coords_tmp(:,2),'y*','Parent',h(1))
                        end
                        hold(h(1),'off');
                        title(h(1),'DAPI Image')
                        
                        imshow(img_mix,[],'Parent',h(2))
                        hold(h(2),'on');
                        if MyoD
                            plot(coords_tmp(coords_mask_tmp & ~sat_tmp,1), ...
                                coords_tmp(coords_mask_tmp & ~sat_tmp,2),'y*','Parent',h(2))
                            plot(coords_tmp(coords_mask_tmp & sat_tmp,1), ...
                                coords_tmp(coords_mask_tmp & sat_tmp,2),'r*','Parent',h(2))
                            legend(h(2),'Nucleus','MyoD+ nucleus')
                        else
                            plot(coords_tmp(coords_mask_tmp,1), ...
                                coords_tmp(coords_mask_tmp,2),'y*','Parent',h(2))
                        end
                        hold(h(2),'off');
                        title(h(2),'Adjusted image with mask')
                        linkaxes(h)
                        
                        app.Stat1EditField.Value = length(coords_tmp);
                        app.Stat2EditField.Value = length(coords_tmp(coords_mask_tmp,1));
                        app.Stat3EditField.Value = length(coords_tmp(coords_mask_tmp,1))/length(coords_tmp);
                        if MyoD
                            app.Stat4EditField.Value = length(find(sat_tmp));
                        end
                        
                        app.EditnucleisegmentationPanel.Visible = 'on';
                        
                    case 4 % Save coordinates and stats
                        app.EditnucleisegmentationPanel.Visible = 'off';
                        
                        data{1,1} = 'X coordinates';
                        data{1,2} = 'Y coordinates';
                        data{1,3} = 'Part of mask?';
                        data{1,4} = 'MyoD+ nucleus?';
                        
                        stats{1,1} = 'Pixel size (µm)';
                        stats{2,1} = 'Total nuclei';
                        stats{3,1} = 'Nuclei in mask';
                        stats{4,1} = 'Fusion index';
                        stats{5,1} = 'MyoD+ nuclei';
                        stats{6,1} = 'MyoD+ in mask';
                        
                        tmp = [pix_size; length(coords_tmp); length(coords_tmp(coords_mask_tmp,1)); ...
                            length(coords_tmp(coords_mask_tmp,1))/length(coords_tmp)];
                        
                        if MyoD
                            data = [data; num2cell(coords_tmp) ...
                                num2cell(coords_mask_tmp) num2cell(sat_tmp)];
                            tmp = [tmp; length(find(sat_tmp)); ...
                                length(find(coords_mask_tmp & sat_tmp))];
                        else
                            data = [data; num2cell(coords_tmp) ...
                                num2cell(coords_mask_tmp) cell(tmp(2),1)];
                            tmp = [tmp; NaN; NaN];
                        end
                        stats = [stats num2cell(tmp)];
                        
                        if ~redo
                            % Clear existing coordinates
                            clearcell = cell(size(inputmatrix));
                            writecell(clearcell,[Files{1} '_output.xlsx'],'Sheet',2,'Range','A2'); 
                        end
                        
                        % Save output
                        writecell(stats,[Files{1} '_output.xlsx'],'Sheet',1);
                        writecell(data,[Files{1} '_output.xlsx'],'Sheet',2);
                        Adj = 0;
                        
                    case 5 % Redo analysis
                        app.EditnucleisegmentationPanel.Visible = 'off';
                        app.StatisticsPanel.Visible = 'off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        % Ask user for pixel size to use
                        prompt = 'Enter pixel size in µm:';
                        dlgtitle = 'Pixel size input';
                        definput = {num2str(app.PixelsizempixelEditField.Value)};
                        answer = inputdlg(prompt,dlgtitle,[1 10],definput);
                        figure(app.UIFigure); % Refocus window
                        if ~isempty(answer) % User did not click cancel
                            pix_size = str2num(answer{1});
                            app.PixelsizempixelEditField.Value = pix_size;
                            
                            % Detection
                            ImBinary = imbinarize(Id,0.05);
                            ImBinary = bwareaopen(ImBinary,50);
                            
                            % Circular filter
                            pix_size = app.PixelsizempixelEditField.Value; % Pixel size
                            radius = 5.136; % Average radius of nucleus
                            H = fspecial('disk',round(radius/pix_size));
                            FiltImg = imfilter(double(Id),H);
                            circ = watershed(-FiltImg);
                            
                            % Add centroids
                            circ(~ImBinary) = 0;
                            regc = regionprops(circ,'centroid');
                            coords_tmp = cat(1,regc.Centroid);
                            coords_tmp = round(rmmissing(coords_tmp));
                            
                            % MyoD+ nuclei check
                            if MyoD
                                sat_tmp = false(length(coords_tmp),1);
                                for i = 1:length(coords_tmp) % Find nuclei in mask
                                    sat_tmp(i) = ImBinary2(round(coords_tmp(i,2)),round(coords_tmp(i,1)));
                                end
                            end
                            
                            % Find nuclei in tubes
                            coords_mask_tmp = false(length(coords_tmp),1);
                            for i = 1:length(coords_tmp)
                                coords_mask_tmp(i) = mask(coords_tmp(i,2),coords_tmp(i,1));
                            end
                            
                            % Images
                            imshow(nuc_img,[],'Parent',h(1))
                            hold(h(1),'on');
                            if MyoD
                                plot(coords_tmp(~sat_tmp,1), ...
                                    coords_tmp(~sat_tmp,2),'y*','Parent',h(1))
                                plot(coords_tmp(sat_tmp,1), ...
                                    coords_tmp(sat_tmp,2),'r*','Parent',h(1))
                                legend(h(1),'Nucleus','MyoD+ nucleus')
                            else
                                plot(coords_tmp(:,1),coords_tmp(:,2),'y*','Parent',h(1))
                            end
                            hold(h(1),'off');
                            title(h(1),'DAPI Image')
                            
                            imshow(img_mix,[],'Parent',h(2))
                            hold(h(2),'on');
                            if MyoD
                                plot(coords_tmp(coords_mask_tmp & ~sat_tmp,1), ...
                                    coords_tmp(coords_mask_tmp & ~sat_tmp,2),'y*','Parent',h(2))
                                plot(coords_tmp(coords_mask_tmp & sat_tmp,1), ...
                                    coords_tmp(coords_mask_tmp & sat_tmp,2),'r*','Parent',h(2))
                                legend(h(2),'Nucleus','MyoD+ nucleus')
                            else
                                plot(coords_tmp(coords_mask_tmp,1), ...
                                    coords_tmp(coords_mask_tmp,2),'y*','Parent',h(2))
                            end
                            hold(h(2),'off');
                            title(h(2),'Adjusted image with mask')
                            linkaxes(h)
                            
                            % Stats
                            app.Stat1EditField.Value = length(coords_tmp);
                            app.Stat2EditField.Value = length(coords_tmp(coords_mask_tmp,1));
                            app.Stat3EditField.Value = length(coords_tmp(coords_mask_tmp,1))/length(coords_tmp);
                            if MyoD
                                app.Stat4EditField.Value = length(find(sat_tmp));
                            end
                        else
                        end
                        app.StatisticsPanel.Visible = 'on';
                        app.ImagecontrolsPanel.Visible = 'on';
                        app.EditnucleisegmentationPanel.Visible = 'on';
                    case 6 % Back
                        app.PixelsizempixelEditField.Value = pix_size_org;
                        Adj = 0;
                    case 100 % Enlarge left image
                        figure(3)
                        imshow(nuc_img,[])
                        hold('on');
                        if MyoD
                            plot(coords_tmp(~sat_tmp,1), ...
                                coords_tmp(~sat_tmp,2),'y*')
                            plot(coords_tmp(sat_tmp,1), ...
                                coords_tmp(sat_tmp,2),'r*')
                            legend('Nucleus','MyoD+ nucleus')
                        else
                            plot(coords_tmp(:,1),coords_tmp(:,2),'y*')
                        end
                        hold('off');
                        title('DAPI Image')
                    case 101 % Enlarge right image
                        figure(4)
                        imshow(img_mix,[])
                        hold('on');
                        if MyoD
                            plot(coords_tmp(coords_mask_tmp & ~sat_tmp,1), ...
                                coords_tmp(coords_mask_tmp & ~sat_tmp,2),'y*')
                            plot(coords_tmp(coords_mask_tmp & sat_tmp,1), ...
                                coords_tmp(coords_mask_tmp & sat_tmp,2),'r*')
                            legend('Nucleus','MyoD+ nucleus')
                        else
                            plot(coords_tmp(coords_mask_tmp,1), ...
                                coords_tmp(coords_mask_tmp,2),'y*')
                        end
                        hold('off');
                        title('Adjusted image with mask')
                end
                Mod = 0;
            end
            app.PixelsizempixelEditField.Enable = 'off';
            app.ImagecontrolsPanel.Visible = 'off';
            app.StatisticsPanel.Visible ='off';
            app.Stat4EditField.Visible = 'on';
            app.Stat4EditFieldLabel.Visible = 'on';
            app.EditnucleisegmentationPanel.Visible = 'off';
            app.SelectfunctionButtonGroup.Visible = 'on';
            app.RightPanel.Title = 'Select function or file';
        end

        % Button pushed function: RemovenucleiButton
        function RemovenucleiButtonPushed(app, event)
            global Mod
            Mod = 1;
            uiresume(app.UIFigure);
        end

        % Button pushed function: AddnucleiButton
        function AddnucleiButtonPushed(app, event)
            global Mod
            Mod = 2;
            uiresume(app.UIFigure);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global Mod
            Mod = 3;
            uiresume(app.UIFigure);
        end

        % Button pushed function: SaveexitButton_2
        function SaveexitButton_2Pushed(app, event)
            global Mod
            Mod = 4;
            uiresume(app.UIFigure);
        end

        % Button pushed function: ClusternucleiButton
        function ClusternucleiButtonPushed(app, event)
            global Files Mod cont
            
            app.SelectfunctionButtonGroup.Visible = 'off';
            
            % Find pixel size
            pix_size = readmatrix([Files{1} '_output.xlsx'],'Sheet',1,'Range','B1:B1');
            app.PixelsizempixelEditField.Value = pix_size;
            
            % Allow user to set parameters
            app.SpecifyparametersPanel.Visible = 'on';
            uiwait(app.UIFigure);
            app.SpecifyparametersPanel.Visible = 'off';
            
            if cont
            % Preparation
            img1 = imread([Files{1} '_Adjusted.png']);
            img2 = imread([Files{2} '_Adjusted.png']);
            img_mask = imread(Files{3});
            img_mask = rgb2gray(img_mask);
            img_mask = imcomplement(img_mask);
            mask = logical(img_mask);
            mask = cat(3,mask,mask,mask);
            
            img_mix = imfuse(img1,img2,'blend','Scaling','independent');
            img_mix(~mask) = 0;
            img_mix(~mask) = 0;
            
            coordinates = readmatrix([Files{1} '_output.xlsx'],'Sheet',2,'Range','A2');
            mask_check = logical(coordinates(:,3));
            
            % Clustering
            Z = linkage(coordinates(mask_check,1:2),'single','euclidean');
            threshold = app.MindistancemEditField.Value + app.NucleusdiametermEditField.Value;
            T = cluster(Z,'cutoff',threshold/pix_size,'Criterion','distance'); % Neighbours have maximum distance of 4 µm between them
            N = groupcounts(T);
            n = N > 3; % Clusters need to have at least 4 members
            k = 1; % Label variable
            % Remove small clusters, redo labels
            for i = 1:length(n)
                tmp = T == i;
                if n(i) == 0
                    T(tmp) = -1;
                else
                    T(tmp) = k;
                    k = k+1;
                end
            end
            
            % Images
            app.RightPanel.AutoResizeChildren = 'off';
            h(1) = subplot(1,2,1,'Parent',app.RightPanel);
            h(2) = subplot(1,2,2,'Parent',app.RightPanel);
            
            imshow(img_mix,[],'Parent',h(1))
            hold(h(1),'on');
            gscatter(h(1),coordinates(mask_check,1), ...
                coordinates(mask_check,2),T);
            hold(h(1),'off');
            title(h(1),'Nuclei clusters')
            
            imshow(imread('placeholder.png'),[],'Parent',h(2))
            title(h(2),'Very Important Image')
            linkaxes(h,'off')
            
            % Save analysis?
            app.SaveanalysisPanel.Visible = 'on';
            app.RightPanel.Title = 'Save cluster labels?';
            uiwait(app.UIFigure);
            switch Mod
                case 1 % Yes
                    stats = cell(5,2);
                    stats{1,1} = 'Number of clusters';
                    stats{1,2} = 0;
                    stats{2,1} = 'Nucleus diameter (µm)';
                    stats{2,2} = app.NucleusdiametermEditField.Value;
                    stats{3,1} = 'Min. distance (µm)';
                    stats{3,2} = app.MindistancemEditField.Value;
                    stats{4,1} = 'Average Rsq';
%                     stats{4,2} = cell(1);
                    stats{5,1} = 'Average RMSE (µm)';
%                     stats{5,2} = cell(1);
                    
                    
                    header{1,1} = 'Cluster';
                    header{1,2} = 'Intercept';
                    header{1,3} = 'Slope';
                    header{1,4} = 'Rsq';
                    header{1,5} = 'Intercept';
                    header{1,6} = 'Slope';
                    header{1,7} = 'RMSE';
                    
                    labels = zeros(length(coordinates),1);
                    labels(mask_check) = T;
                    labels(~mask_check) = -1;
                    
                    if ~(max(T) == -1) % Only do regression if clusters exist
                        stats{1,2} = max(T); % Number of clusters
                        
                        % Regression analysis
                        coordinates(:,1:2) = coordinates(:,1:2)*pix_size;
                        beta = zeros(2,1);
                        RMSE = zeros(1);
                        coefficients = cell(2,1);
                        Rsq = cell(1);
                        for i = 1:max(labels)
                            % Linear regression
                            mdl = fitlm(coordinates(labels == i,1),coordinates(labels == i,2));
                            tmp = table2cell(mdl.Coefficients(1:2,1));
                            coefficients{1,i} = tmp{1};
                            coefficients{2,i} = tmp{2};
                            Rsq{i} = mdl.Rsquared.Ordinary;
                            
                            % Orthogonal regression
                            x = coordinates(labels == i,1);
                            y = coordinates(labels == i,2);
                            n = length(x);
                            
                            covariance = cov(x,y);
                            s_xx = covariance(1,1);
                            s_xy = covariance(1,2);
                            s_yy = covariance(2,2);
                            
                            % Determine slope and intercept
                            beta(2,i) = (s_yy - s_xx + sqrt(((s_yy - s_xx)^2) + 4*(s_xy^2)))/(2*s_xy); % Slope
                            beta(1,i) = mean(y) - beta(2,i)*mean(x); % Intercept
                            
                            % Estimated values
                            x_est        = x + (beta(2,i)./(beta(2,i).^2 + 1))*(y - beta(1,i) - beta(2,i)*x);
                            y_est        = y - (y - beta(1,i) - beta(2,i)*x)/(beta(2,i).^2 + 1);
                            
                            % Residuals
                            d_x = x - x_est;
                            d_y = y - y_est;
                            d = d_x.^2 + d_y.^2;
                            
                            % RMSE
                            RMSE(i) = sqrt(sum(d)/n);
                        end
                        clusters = 1:max(labels);
                        stats{4,2} = mean(cell2mat(Rsq));
                        stats{5,2} = mean(RMSE);
                        data = [num2cell(clusters); coefficients; Rsq; num2cell(beta); num2cell(RMSE)]';
                        data = [header; data];
                    else
                        data = header;
                    end
                    
                    % Clear existing data if necessary
                    try
                        inputmatrix = readmatrix([Files{1} '_output.xlsx'],'Sheet',3);
                        clearcell = cell(size(inputmatrix));
                        writecell(clearcell,[Files{1} '_output.xlsx'],'Sheet',3,'Range','A2'); 
                    catch ME % If no data is present yet
                    end
                    
                    % Save figure
                    f = figure;
                    f.Visible = 'off';
                    newfig = copyobj(app.RightPanel.Children,f);
                    saveas(f,[Files{1} '_clusters.png'])
                    close(f)
                    
                    % Write data
                    labels = ['Cluster label'; num2cell(labels)];
                    writecell(stats, [Files{1} '_output.xlsx'], ...
                        'Sheet',1,'Range','A8');
                    writecell(labels,[Files{1} '_output.xlsx'], ...
                        'Sheet',2,'Range','E1');
                    writecell(data, [Files{1} '_output.xlsx'], ...
                        'Sheet',3);
                    
                        
                    app.RightPanel.Title = 'Analysis saved!';
                case 2 % No
                    app.RightPanel.Title = 'Select function or file';
            end
            app.SaveanalysisPanel.Visible = 'off';
            else % Go back to function selection
            end
            app.SelectfunctionButtonGroup.Visible = 'on';
            Mod = 0;
            
             
        end

        % Button pushed function: YesButton_2
        function YesButton_2Pushed(app, event)
            global Mod
            Mod = 1;
            uiresume(app.UIFigure);
        end

        % Button pushed function: NoButton_2
        function NoButton_2Pushed(app, event)
            global Mod
            Mod = 2;
            uiresume(app.UIFigure);
        end

        % Button pushed function: BranchingpointsButton
        function BranchingpointsButtonPushed(app, event)
            global Files Mod Adj done cont back undo f
            
            app.SelectfunctionButtonGroup.Visible = 'off';
            
            % Find pixel size
            
            % Load images
            img1 = imread([Files{1} '_Adjusted.png']);
            img2 = imread([Files{2} '_Adjusted.png']);
            img_mask = imread(Files{3});
            
            % Find branching points
            if exist([Files{1} '_output.xlsx'],'file')
                try
                    A = readmatrix([Files{1} '_output.xlsx'],'Sheet',1,'Range','B1:B18');
                    pix_size = A(1);
                    app.PixelsizempixelEditField.Value = pix_size;
                    app.MinlengthEditField.Value = A(17);
                    inputmatrix = readmatrix([Files{1} '_output.xlsx'],'Sheet',5,'Range','A2');
                    redo = 0;
                catch ME
                    redo = 1;
                end
            else
                redo = 1;
            end
            
            % Redo mask
            img_mask = rgb2gray(img_mask);
            img_mask = imcomplement(img_mask);
            mask = logical(img_mask);
            mask = cat(3,mask,mask,mask);
            
            % Burn mask in adjusted image
            img_mix = imfuse(img1,img2,'blend','Scaling','independent');
            img_mix(~mask) = 0;
            img_mix(~mask) = 0;
            
            % Find skeleton
            mask_open = bwmorph(mask(:,:,1),'open',2);
            skel = bwskel(mask_open,'MinBranchLength',app.MinlengthEditField.Value);
            thin = bwmorph(skel,'spur',5);
            img_skel = imoverlay(img_mix,skel,'white');
            
            if redo == 1 % Do analysis again
                [x_branch,y_branch] = find(bwmorph(thin,'branchpoints'));
            else % Using existing coordinates
                x_branch = inputmatrix(:,1);
                y_branch = inputmatrix(:,2);
            end
            
            % Statistics
            n_branch = length(x_branch); % Amount of branching points
            n_myo = max(max(bwlabel(mask(:,:,1)))); % Amount of myotubes
            
            app.Stat1EditFieldLabel.Text = 'Branching points';
            app.Stat2EditFieldLabel.Text = 'Myotubes';
            app.Stat3EditFieldLabel.Text = 'Points/myotube';
            
            app.Stat1EditField.Value = n_branch;
            app.Stat2EditField.Value = n_myo;
            app.Stat3EditField.Value = n_branch/n_myo;
            
            app.Stat4EditField.Visible = 'off';
            app.Stat4EditFieldLabel.Visible = 'off';
            app.StatisticsPanel.Visible ='on';
            
            % Images
            app.RightPanel.AutoResizeChildren = 'off';
            h(1) = subplot(1,2,1,'Parent',app.RightPanel);
            h(2) = subplot(1,2,2,'Parent',app.RightPanel);
            
            imshow(img_skel,[],'Parent',h(1))
            hold(h(1),'on');
            plot(y_branch,x_branch,'go','Parent',h(1))
            hold(h(1),'off');
            title(h(1),'Skeleton + Branching Points')
            
            mask_overlay = mask(:,:,1);
            mask_overlay(skel) = 0;
            imshow(mask_overlay,'Parent',h(2))
            title(h(2),'Binary mask')
            
            % Allow editing of branching points
            done = 0;
            undo = 0;
            cont = 0;
            back = 0;
            Adj = 1;
            while Adj
                app.EditbranchingpointsPanel.Visible = 'on';
                app.ImagecontrolsPanel.Visible = 'on';
                app.RightPanel.Title = 'Add/remove branching points or save stats to table';
                uiwait(app.UIFigure);
                switch Mod
                    case 1 % Remove points
                        app.EditbranchingpointsPanel.Visible = 'off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        f = figure('DeleteFcn',@figDelete);
                        imshow(img_skel,[])
                        hold on
                        plot(y_branch,x_branch,'go')
                        hold off
                        app.RemoveCheckBox.Visible = 'off';
                        app.ConfirmmodificationPanel.Visible = 'on';
                        app.RightPanel.Title = 'Draw to mark area for branching point removal';
                        i = 1;
                        while ~done
                            if ~isempty(app.UIFigure) % Prevent endless loop if app is closed
                            % Have user draw on image
                            ROI(i) = drawfreehand(gca);
                            drawnow
                            if undo
                                delete(ROI(end-1:end)); % Remove 2 last drawn lines
                                ROI(end-1:end) = [];
                                undo = 0;
                            end
                            i = length(ROI) + 1;
                            end
                        end
                        delete(ROI(end)); % Remove last drawn line
                        ROI(end) = [];
                        app.ConfirmmodificationPanel.Visible = 'off';
                        
                        if cont % Burn area in image
                            for i = 1:length(ROI)
                                freehand_pos = ROI(i).Position;
                                freehand_mask = poly2mask(freehand_pos(:,1), freehand_pos(:,2), ...
                                    size(img_skel,1), size(img_skel,2) ); % Create area mask
                                check = false(length(x_branch),1);
                                for j = 1:length(x_branch) % Find points in mask
                                    check(j) = freehand_mask(x_branch(j),y_branch(j));
                                end
                                x_branch(check) = []; % Remove points found in mask
                                y_branch(check) = []; % Remove points found in mask
                                cont = 0;
                            end
                            
                            % Statistics
                            n_branch = length(x_branch); % Amount of branching points
                            n_myo = max(max(bwlabel(mask(:,:,1)))); % Amount of myotubes
                            app.Stat1EditField.Value = n_branch;
                            app.Stat2EditField.Value = n_myo;
                            app.Stat3EditField.Value = n_branch/n_myo;
                            
                            % Images
                            imshow(img_skel,[],'Parent',h(1))
                            hold(h(1),'on');
                            plot(y_branch,x_branch,'go','Parent',h(1))
                            hold(h(1),'off');
                            title(h(1),'Skeleton + Branching Points')
                            
                        elseif back % Return without doing anything
                            back = 0;
                        end
                        
                        clear ROI
                        if ishandle(f)
                        close(f)
                        end
                        done = 0;
                        undo = 0;
                        
                    case 2 % Add points
                        app.EditbranchingpointsPanel.Visible = 'off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        
                        figure(1)
                        imshow(img_skel,[])
                        hold on
                        plot(y_branch,x_branch,'go')
                        hold off
                        
                        % Allow user to indicate extra points
                        app.RightPanel.Title = 'Click to add branching points: backspace to remove last, enter to finish';
                        try
                            [y_extra,x_extra,~] = impixel;
                            skip = 0;
                        catch ME
                            if strcmp(ME.identifier,'images:getpts:interruptedMouseSelection')
                                skip = 1;
                            else
                                rethrow(ME)
                            end
                        end
                        
                        if ~skip % Does not execute if user closes figure window
                            % Check for points outside the mask
                            error_check = false(length(x_extra),1);
                            error_check = x_extra > size(img_skel,1) | x_extra < 0;
                            x_extra(error_check) = [];
                            y_extra(error_check) = [];
                            error_check = false(length(y_extra),1);
                            error_check = y_extra > size(img_skel,2) | y_extra < 0;
                            x_extra(error_check) = [];
                            y_extra(error_check) = [];
                            
                            
                            x_branch = cat(1,x_branch,x_extra); % Add to existing set
                            y_branch = cat(1,y_branch,y_extra); % Add to existing set
                            
                            n_branch = length(x_branch); % Amount of branching points
                            n_myo = max(max(bwlabel(mask(:,:,1)))); % Amount of myotubes
                            app.Stat1EditField.Value = n_branch;
                            app.Stat2EditField.Value = n_myo;
                            app.Stat3EditField.Value = n_branch/n_myo;
                            
                            imshow(img_skel,[],'Parent',h(1))
                            hold(h(1),'on');
                            plot(y_branch,x_branch,'go','Parent',h(1))
                            hold(h(1),'off');
                            title(h(1),'Skeleton + Branching Points')
                            
                            close(1)
                        else
                        end
                        
                    case 3 % Done
                        app.EditbranchingpointsPanel.Visible = 'off';
                        
                        % Statistics
                        stats{1,1} = 'Branching points';
                        stats{2,1} = 'Myotubes';
                        stats{3,1} = 'Points/myotube';
                        stats{4,1} = 'Min Branch length';
                        stats{5,1} = 'Myotube coverage (%)';
                        stats{1,2} = n_branch;
                        stats{2,2} = n_myo;
                        stats{3,2} = n_branch/n_myo;
                        stats{4,2} = app.MinlengthEditField.Value;
                        
                        coords{1,1} = 'X coordinates';
                        coords{1,2} = 'Y coordinates';
                        coords = [coords; num2cell([x_branch y_branch])];
                        
                        % Myotube areas and percentages
                        tubes{1,1} = 'Label';
                        tubes{1,2} = 'Total Area (µm²)';
                        tubes{1,3} = '% of myotube area';
                        tubes{1,4} = '% of image area';
                        BW = bwlabel(mask(:,:,1));
                        rprops = regionprops(BW,'Area','Centroid');
                        areas = (cat(1,rprops.Area)).*(pix_size)^2;
                        
                        img_area = numel(mask_open)*(pix_size)^2;
                        myo_area = nnz(mask_open)*(pix_size)^2;
                        pct1 = (areas/myo_area).*100;
                        pct2 = (areas/img_area)*100;
                        stats{5,2} = myo_area/img_area; % Myotube coverage
                        
                        [areas,labels] = sort(areas,'descend');
                        tubes = [tubes; num2cell([labels areas pct1(labels) pct2(labels)])];
                        centroids = cat(1,rprops.Centroid);
                        
                        % Save image with labels
                        RGB_label = label2rgb(BW,'jet',[1,1,1]);
                        img_label = insertText(RGB_label,centroids,1:length(labels),'FontSize',11, ...
                        'TextColor','white','BoxColor','black','AnchorPoint','Center');
                        imwrite(img_label,[Files{1} '_labels.png'],'png');
                        
                        if ~redo
                            % Clear existing coordinates
                            clearcell = cell(size(inputmatrix));
                            writecell(clearcell,[Files{1} '_output.xlsx'],'Sheet',5,'Range','A2');
                            % Clear existing myotube data
                            inputmatrix = readmatrix([Files{1} '_output.xlsx'],'Sheet',4,'Range','A2');
                            clearcell = cell(size(inputmatrix));
                            writecell(clearcell,[Files{1} '_output.xlsx'],'Sheet',4,'Range','A2');
                        end
                        
                        writecell(stats,[Files{1} '_output.xlsx'],'Sheet',1,'Range','A14');
                        writecell(tubes,[Files{1} '_output.xlsx'],'Sheet',4,'Range','A1');
                        writecell(coords,[Files{1} '_output.xlsx'],'Sheet',5,'Range','A1');
                        Adj = 0;
                        
                    case 4 % Redo
                        app.EditbranchingpointsPanel.Visible = 'off';
                        
                        % Find skeleton and branching points
                        mask_open = bwmorph(mask(:,:,1),'open',2);
                        skel = bwskel(mask_open,'MinBranchLength',app.MinlengthEditField.Value);
                        thin = bwmorph(skel,'spur',5);
                        [x_branch,y_branch] = find(bwmorph(thin,'branchpoints'));
                        img_skel = imoverlay(img_mix,skel,'white');
                        
                        % Statistics
                        n_branch = length(x_branch); % Amount of branching points
                        n_myo = max(max(bwlabel(mask(:,:,1)))); % Amount of myotubes
                        app.Stat1EditField.Value = n_branch;
                        app.Stat2EditField.Value = n_myo;
                        app.Stat3EditField.Value = n_branch/n_myo;
                        
                        % Images
                        imshow(img_skel,[],'Parent',h(1))
                        hold(h(1),'on');
                        plot(y_branch,x_branch,'go','Parent',h(1))
                        hold(h(1),'off');
                        title(h(1),'Skeleton + Branching Points')
                        
                        mask_overlay = mask(:,:,1);
                        mask_overlay(skel) = 0;
                        imshow(mask_overlay,'Parent',h(2))
                        title(h(2),'Binary mask')
                        
                        
                    case 5 % Find diameters
                        app.EditbranchingpointsPanel.Visible = 'off';
                        app.ImagecontrolsPanel.Visible = 'off';
                        
                        edtImage = 2 * bwdist(~mask(:,:,1)); % Compute distance
                        img_dist = cat(3,edtImage,edtImage,edtImage);
                        img_dist = (img_dist/max(max(max(img_dist))));
                        img_dist = imoverlay(img_dist,skel,'Red');
                        
                        % Let user choose a point
                        figure(1)
                        imshow(img_dist,[])
                        
                        app.RightPanel.Title = 'Click to choose points for diameter measurement: backspace to remove last, enter to finish';
                        
                        [Dy,Dx,~] = impixel;
                        
                        n = length(Dx);
                        for i = 1:n
                            diameters(i) = edtImage(Dx(i),Dy(i));
                        end
                        
                        hold on
                        plot(Dy,Dx,'y*') % Show on image
                        hold off
                        
                        app.SaveanalysisPanel.Visible = 'on';
                        app.SaveanalysisPanel.Title = 'Save stats?';
                        app.RightPanel.Title = 'Save stats to Excel?';
                        figure(app.UIFigure);
                        uiwait(app.UIFigure);
                        
                        switch Mod
                            case 1 % Yes
                                header{1,1} = 'Average';
                                header{1,2} = mean(diameters);
                                header{2,1} = 'Std dev';
                                header{2,2} = std(diameters);
                                header{3,1} = 'X coordinates';
                                header{3,2} = 'Y coordinates';
                                header{3,3} = 'Diameter';
                                
                                writecell([header; num2cell([Dy,Dx]) ...
                                    num2cell(diameters')],[Files{1} '_output.xlsx'], ...
                                    'Sheet',6,'WriteMode','append')
                                
                                imwrite(img_dist,[Files{1} '_diameters.png'],'png')
                            case 2 % No
                        end
                        
                        close(1)
                        app.SaveanalysisPanel.Visible = 'off';
                        app.SaveanalysisPanel.Title = 'Save analysis?';
                        Mod = 0;
                        
                    case 6 % Back
                        Adj = 0;
                    case 100 % Enlarge left image
                        figure(3)
                        imshow(img_skel,[])
                        hold('on');
                        plot(y_branch,x_branch,'go')
                        hold('off');
                        title('Skeleton + Branching Points')
                    case 101 % Enlarge right image
                        figure(4)
                        imshow(mask_overlay)
                        title('Binary mask')
                end
            end
            app.ImagecontrolsPanel.Visible = 'off';
            app.StatisticsPanel.Visible ='off';
            app.Stat4EditField.Visible = 'on';
            app.Stat4EditFieldLabel.Visible = 'on';
            app.EditbranchingpointsPanel.Visible = 'off';
            app.SelectfunctionButtonGroup.Visible = 'on';
        end

        % Button pushed function: RemovepointsButton
        function RemovepointsButtonPushed(app, event)
            global Mod
            Mod = 1;
            uiresume(app.UIFigure);
        end

        % Button pushed function: AddpointsButton
        function AddpointsButtonPushed(app, event)
            global Mod
            Mod = 2;
            uiresume(app.UIFigure);
        end

        % Button pushed function: SaveexitButton_3
        function SaveexitButton_3Pushed(app, event)
            global Mod
            Mod = 3;
            uiresume(app.UIFigure);
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            global Mod
            Mod = 4;
            uiresume(app.UIFigure);
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            global Mod
            Mod = 6;
            uiresume(app.UIFigure);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.LowerinEditField.Value = app.Slider.Value;
            
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.UpperinEditField.Value = app.Slider_2.Value;
            
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            app.LoweroutEditField.Value = app.Slider_3.Value;
            
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            app.UpperoutEditField.Value = app.Slider_4.Value;
            
        end

        % Value changed function: UpperoutEditField
        function UpperoutEditFieldValueChanged(app, event)
            app.Slider_4.Value = app.UpperoutEditField.Value;
            
        end

        % Value changed function: LoweroutEditField
        function LoweroutEditFieldValueChanged(app, event)
            app.Slider_3.Value = app.LoweroutEditField.Value;
            
        end

        % Value changed function: UpperinEditField
        function UpperinEditFieldValueChanged(app, event)
            app.Slider_2.Value = app.UpperinEditField.Value;
            
        end

        % Value changed function: LowerinEditField
        function LowerinEditFieldValueChanged(app, event)
            app.Slider.Value = app.LowerinEditField.Value;
            
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            app.ThresholdEditField.Value = app.Slider_5.Value;
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            app.Slider_5.Value = app.ThresholdEditField.Value;
            
        end

        % Button pushed function: FinddiameterButton_2
        function FinddiameterButton_2Pushed(app, event)
            global Mod
            Mod = 5;
            uiresume(app.UIFigure);
        end

        % Button pushed function: SelectFileButton_3
        function SelectFileButton_3Pushed(app, event)
            global Files
            [FileName,PathName,FilterIndex] = uigetfile({'*.png';'*.jpg';}, ...
                'File Selector');
            app.EditField_3.Value = FileName;
            
            figure(app.UIFigure) % Refocus window
            if FilterIndex
            
            A = strsplit(FileName,'.');
            FileNameS = [A(1:(end-1))];
            FileNameS = FileNameS{1};
            
            Files{4} = FileNameS;
            cd(PathName)
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            uiresume(app.UIFigure);
        end

        % Button pushed function: DoneButton_4
        function DoneButton_4Pushed(app, event)
            global Mod
            Mod = 2;
            uiresume(app.UIFigure);
        end

        % Button pushed function: AdjustButton_2
        function AdjustButton_2Pushed(app, event)
            global Mod
            Mod = 1;
            uiresume(app.UIFigure);
        end

        % Button pushed function: RedoanalysisButton
        function RedoanalysisButtonPushed(app, event)
            global Mod
            Mod = 5;
            uiresume(app.UIFigure);
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            global cont
            cont = 0;
            uiresume(app.UIFigure);
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            global cont
            cont = 1;
            uiresume(app.UIFigure);
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
            global Mod
            Mod = 3;
            uiresume(app.UIFigure);
        end

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
            global Mod
            Mod = 7;
            uiresume(app.UIFigure);
        end

        % Button pushed function: ThresholdingButton
        function ThresholdingButtonPushed(app, event)
            global Mod
            Mod = 8;
            uiresume(app.UIFigure);
        end

        % Button pushed function: BackButton_6
        function BackButton_6Pushed(app, event)
            global Mod
            Mod = 6;
            uiresume(app.UIFigure);
        end

        % Button pushed function: EnlargeleftimageButton
        function EnlargeleftimageButtonPushed(app, event)
            global Mod
            Mod = 100;
            uiresume(app.UIFigure);
        end

        % Button pushed function: EnlargerightimageButton
        function EnlargerightimageButtonPushed(app, event)
            global Mod;
            Mod = 101;
            uiresume(app.UIFigure);
        end

        % Button pushed function: BackButton_7
        function BackButton_7Pushed(app, event)
            global Mod
            Mod = 3;
            uiresume(app.UIFigure);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {775, 775};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectFileButton = uibutton(app.LeftPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.SelectFileButton_2 = uibutton(app.LeftPanel, 'push');
            app.SelectFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_2Pushed, true);
            app.SelectFileButton_2.Text = 'Select File';
            app.DAPIImageLabel = uilabel(app.LeftPanel);
            app.DAPIImageLabel.Text = 'DAPI Image';
            app.MyHCImageLabel = uilabel(app.LeftPanel);
            app.MyHCImageLabel.Text = 'MyHC Image';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.LeftPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.ConfirmmodificationPanel = uipanel(app.LeftPanel);
            app.ConfirmmodificationPanel.Title = 'Confirm modification?';
            app.ConfirmmodificationPanel.Visible = 'off';
            app.UndoButton = uibutton(app.ConfirmmodificationPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Tag = 'save2excel';
            app.UndoButton.Text = 'Undo';
            app.BackButton = uibutton(app.ConfirmmodificationPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Tag = 'save2excel';
            app.BackButton.Text = 'Back';
            app.DoneButton_2 = uibutton(app.ConfirmmodificationPanel, 'push');
            app.DoneButton_2.ButtonPushedFcn = createCallbackFcn(app, @DoneButton_2Pushed, true);
            app.DoneButton_2.Tag = 'save2excel';
            app.DoneButton_2.Text = 'Done';
            app.RemoveCheckBox = uicheckbox(app.ConfirmmodificationPanel);
            app.RemoveCheckBox.Text = 'Remove';
            app.RemoveCheckBox.Value = true;
            app.SaveanalysisPanel = uipanel(app.LeftPanel);
            app.SaveanalysisPanel.Title = 'Save analysis?';
            app.SaveanalysisPanel.Visible = 'off';
            app.YesButton_2 = uibutton(app.SaveanalysisPanel, 'push');
            app.YesButton_2.ButtonPushedFcn = createCallbackFcn(app, @YesButton_2Pushed, true);
            app.YesButton_2.Text = 'Yes';
            app.NoButton_2 = uibutton(app.SaveanalysisPanel, 'push');
            app.NoButton_2.ButtonPushedFcn = createCallbackFcn(app, @NoButton_2Pushed, true);
            app.NoButton_2.Text = 'No';
            app.StatisticsPanel = uipanel(app.LeftPanel);
            app.StatisticsPanel.Title = 'Statistics';
            app.StatisticsPanel.Visible = 'off';
            app.Stat1EditFieldLabel = uilabel(app.StatisticsPanel);
            app.Stat1EditFieldLabel.Text = 'Stat1';
            app.Stat1EditField = uieditfield(app.StatisticsPanel, 'numeric');
            app.Stat1EditField.Editable = 'off';
            app.Stat2EditFieldLabel = uilabel(app.StatisticsPanel);
            app.Stat2EditFieldLabel.Text = 'Stat2';
            app.Stat2EditField = uieditfield(app.StatisticsPanel, 'numeric');
            app.Stat2EditField.Editable = 'off';
            app.Stat3EditFieldLabel = uilabel(app.StatisticsPanel);
            app.Stat3EditFieldLabel.Text = 'Stat3';
            app.Stat3EditField = uieditfield(app.StatisticsPanel, 'numeric');
            app.Stat3EditField.Editable = 'off';
            app.Stat4EditFieldLabel = uilabel(app.StatisticsPanel);
            app.Stat4EditFieldLabel.Text = 'Stat4';
            app.Stat4EditField = uieditfield(app.StatisticsPanel, 'numeric');
            app.Stat4EditField.Editable = 'off';
            app.SelectFileButton_3 = uibutton(app.LeftPanel, 'push');
            app.SelectFileButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_3Pushed, true);
            app.SelectFileButton_3.Text = 'Select File';
            app.MyoDImageLabel = uilabel(app.LeftPanel);
            app.MyoDImageLabel.Text = 'MyoD Image';
            app.EditField_3 = uieditfield(app.LeftPanel, 'text');
            app.EditField_3.Editable = 'off';
            app.PixelsizempixelEditFieldLabel = uilabel(app.LeftPanel);
            app.PixelsizempixelEditFieldLabel.Text = 'Pixel size (µm/pixel)';
            app.PixelsizempixelEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PixelsizempixelEditField.Limits = [0.01 Inf];
            app.PixelsizempixelEditField.ValueDisplayFormat = '%.3f';
            app.PixelsizempixelEditField.Enable = 'off';
            app.PixelsizempixelEditField.Value = 0.664;
            app.SelectimageButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectimageButtonGroup.Title = 'Select image';
            app.SelectimageButtonGroup.Visible = 'off';
            app.DAPIButton = uiradiobutton(app.SelectimageButtonGroup);
            app.DAPIButton.Text = 'DAPI';
            app.DAPIButton.Value = true;
            app.MyHCButton = uiradiobutton(app.SelectimageButtonGroup);
            app.MyHCButton.Text = 'MyHC';
            app.MyoDButton = uiradiobutton(app.SelectimageButtonGroup);
            app.MyoDButton.Text = 'MyoD';
            app.RunButton = uibutton(app.SelectimageButtonGroup, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AdjustimagelevelsPanel = uipanel(app.LeftPanel);
            app.AdjustimagelevelsPanel.Title = 'Adjust image levels';
            app.AdjustimagelevelsPanel.Visible = 'off';
            app.AdjustButton = uibutton(app.AdjustimagelevelsPanel, 'push');
            app.AdjustButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustButtonPushed, true);
            app.AdjustButton.Text = 'Adjust';
            app.SaveexitButton_4 = uibutton(app.AdjustimagelevelsPanel, 'push');
            app.SaveexitButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveexitButton_4Pushed, true);
            app.SaveexitButton_4.Text = 'Save & exit';
            app.Slider = uislider(app.AdjustimagelevelsPanel);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2 = uislider(app.AdjustimagelevelsPanel);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 1;
            app.Slider_3 = uislider(app.AdjustimagelevelsPanel);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4 = uislider(app.AdjustimagelevelsPanel);
            app.Slider_4.Limits = [0 1];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.Value = 1;
            app.LowerinLabel = uilabel(app.AdjustimagelevelsPanel);
            app.LowerinLabel.Text = 'Lower in';
            app.UpperinLabel = uilabel(app.AdjustimagelevelsPanel);
            app.UpperinLabel.Text = 'Upper in';
            app.LoweroutLabel = uilabel(app.AdjustimagelevelsPanel);
            app.LoweroutLabel.Text = 'Lower out';
            app.UpperoutLabel = uilabel(app.AdjustimagelevelsPanel);
            app.UpperoutLabel.Text = 'Upper out';
            app.LowerinEditField = uieditfield(app.AdjustimagelevelsPanel, 'numeric');
            app.LowerinEditField.ValueDisplayFormat = '%.2f';
            app.LowerinEditField.ValueChangedFcn = createCallbackFcn(app, @LowerinEditFieldValueChanged, true);
            app.UpperinEditField = uieditfield(app.AdjustimagelevelsPanel, 'numeric');
            app.UpperinEditField.ValueDisplayFormat = '%.2f';
            app.UpperinEditField.ValueChangedFcn = createCallbackFcn(app, @UpperinEditFieldValueChanged, true);
            app.UpperinEditField.Value = 1;
            app.LoweroutEditField = uieditfield(app.AdjustimagelevelsPanel, 'numeric');
            app.LoweroutEditField.ValueDisplayFormat = '%.2f';
            app.LoweroutEditField.ValueChangedFcn = createCallbackFcn(app, @LoweroutEditFieldValueChanged, true);
            app.UpperoutEditField = uieditfield(app.AdjustimagelevelsPanel, 'numeric');
            app.UpperoutEditField.ValueDisplayFormat = '%.2f';
            app.UpperoutEditField.ValueChangedFcn = createCallbackFcn(app, @UpperoutEditFieldValueChanged, true);
            app.UpperoutEditField.Value = 1;
            app.BackButton_4 = uibutton(app.AdjustimagelevelsPanel, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back';
            app.SelectfunctionButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectfunctionButtonGroup.Title = 'Select function';
            app.AdjustlevelsButton = uibutton(app.SelectfunctionButtonGroup, 'push');
            app.AdjustlevelsButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustlevelsButtonPushed, true);
            app.AdjustlevelsButton.Text = 'Adjust levels';
            app.EditmaskButton = uibutton(app.SelectfunctionButtonGroup, 'push');
            app.EditmaskButton.ButtonPushedFcn = createCallbackFcn(app, @EditmaskButtonPushed, true);
            app.EditmaskButton.Text = 'Edit mask';
            app.IndicatenucleiButton = uibutton(app.SelectfunctionButtonGroup, 'push');
            app.IndicatenucleiButton.ButtonPushedFcn = createCallbackFcn(app, @IndicatenucleiButtonPushed, true);
            app.IndicatenucleiButton.Text = 'Indicate nuclei';
            app.ClusternucleiButton = uibutton(app.SelectfunctionButtonGroup, 'push');
            app.ClusternucleiButton.ButtonPushedFcn = createCallbackFcn(app, @ClusternucleiButtonPushed, true);
            app.ClusternucleiButton.Text = 'Cluster nuclei';
            app.BranchingpointsButton = uibutton(app.SelectfunctionButtonGroup, 'push');
            app.BranchingpointsButton.ButtonPushedFcn = createCallbackFcn(app, @BranchingpointsButtonPushed, true);
            app.BranchingpointsButton.Text = 'Branching points';
            app.SpecifyparametersPanel = uipanel(app.LeftPanel);
            app.SpecifyparametersPanel.Title = 'Specify parameters';
            app.SpecifyparametersPanel.Visible = 'off';
            app.NucleusdiametermEditFieldLabel = uilabel(app.SpecifyparametersPanel);
            app.NucleusdiametermEditFieldLabel.Text = 'Nucleus diameter (µm)';
            app.NucleusdiametermEditField = uieditfield(app.SpecifyparametersPanel, 'numeric');
            app.NucleusdiametermEditField.Value = 10;
            app.MindistancemEditFieldLabel = uilabel(app.SpecifyparametersPanel);
            app.MindistancemEditFieldLabel.Text = 'Min distance (µm)';
            app.MindistancemEditField = uieditfield(app.SpecifyparametersPanel, 'numeric');
            app.MindistancemEditField.Value = 4;
            app.RunButton_2 = uibutton(app.SpecifyparametersPanel, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.BackButton_2 = uibutton(app.SpecifyparametersPanel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.EditbranchingpointsPanel = uipanel(app.LeftPanel);
            app.EditbranchingpointsPanel.Title = 'Edit branching points';
            app.EditbranchingpointsPanel.Visible = 'off';
            app.RemovepointsButton = uibutton(app.EditbranchingpointsPanel, 'push');
            app.RemovepointsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovepointsButtonPushed, true);
            app.RemovepointsButton.Text = 'Remove points';
            app.AddpointsButton = uibutton(app.EditbranchingpointsPanel, 'push');
            app.AddpointsButton.ButtonPushedFcn = createCallbackFcn(app, @AddpointsButtonPushed, true);
            app.AddpointsButton.Text = 'Add points';
            app.SaveexitButton_3 = uibutton(app.EditbranchingpointsPanel, 'push');
            app.SaveexitButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveexitButton_3Pushed, true);
            app.SaveexitButton_3.Text = 'Save & exit';
            app.RedoButton = uibutton(app.EditbranchingpointsPanel, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'Redo';
            app.BackButton_3 = uibutton(app.EditbranchingpointsPanel, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.MinlengthEditFieldLabel = uilabel(app.EditbranchingpointsPanel);
            app.MinlengthEditFieldLabel.Text = 'Min. length';
            app.MinlengthEditField = uieditfield(app.EditbranchingpointsPanel, 'numeric');
            app.MinlengthEditField.Value = 100;
            app.FinddiameterButton_2 = uibutton(app.EditbranchingpointsPanel, 'push');
            app.FinddiameterButton_2.ButtonPushedFcn = createCallbackFcn(app, @FinddiameterButton_2Pushed, true);
            app.FinddiameterButton_2.Text = 'Find diameter';
            app.EditmaskPanel = uipanel(app.LeftPanel);
            app.EditmaskPanel.Title = 'Edit mask';
            app.EditmaskPanel.Visible = 'off';
            app.SeparatinglineButton = uibutton(app.EditmaskPanel, 'push');
            app.SeparatinglineButton.ButtonPushedFcn = createCallbackFcn(app, @SeparatinglineButtonPushed, true);
            app.SeparatinglineButton.Text = 'Separating line';
            app.EditareaButton = uibutton(app.EditmaskPanel, 'push');
            app.EditareaButton.ButtonPushedFcn = createCallbackFcn(app, @EditareaButtonPushed, true);
            app.EditareaButton.Text = 'Edit area';
            app.FillholesButton = uibutton(app.EditmaskPanel, 'push');
            app.FillholesButton.ButtonPushedFcn = createCallbackFcn(app, @FillholesButtonPushed, true);
            app.FillholesButton.Text = 'Fill holes';
            app.RemovejunkButton = uibutton(app.EditmaskPanel, 'push');
            app.RemovejunkButton.ButtonPushedFcn = createCallbackFcn(app, @RemovejunkButtonPushed, true);
            app.RemovejunkButton.Text = 'Remove junk';
            app.SaveexitButton = uibutton(app.EditmaskPanel, 'push');
            app.SaveexitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveexitButtonPushed, true);
            app.SaveexitButton.Text = 'Save & exit';
            app.ResetmaskButton = uibutton(app.EditmaskPanel, 'push');
            app.ResetmaskButton.ButtonPushedFcn = createCallbackFcn(app, @ResetmaskButtonPushed, true);
            app.ResetmaskButton.Text = 'Reset mask';
            app.BackButton_5 = uibutton(app.EditmaskPanel, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = 'Back';
            app.ThresholdingButton = uibutton(app.EditmaskPanel, 'push');
            app.ThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdingButtonPushed, true);
            app.ThresholdingButton.Text = 'Thresholding';
            app.ImagecontrolsPanel = uipanel(app.LeftPanel);
            app.ImagecontrolsPanel.Title = 'Image controls';
            app.ImagecontrolsPanel.Visible = 'off';
            app.EnlargeleftimageButton = uibutton(app.ImagecontrolsPanel, 'push');
            app.EnlargeleftimageButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeleftimageButtonPushed, true);
            app.EnlargeleftimageButton.Text = 'Enlarge left image';
            app.EnlargerightimageButton = uibutton(app.ImagecontrolsPanel, 'push');
            app.EnlargerightimageButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargerightimageButtonPushed, true);
            app.EnlargerightimageButton.Text = 'Enlarge right image';
            app.AdjustthresholdPanel = uipanel(app.LeftPanel);
            app.AdjustthresholdPanel.Title = 'Adjust threshold';
            app.AdjustthresholdPanel.Visible = 'off';
            app.AdjustButton_2 = uibutton(app.AdjustthresholdPanel, 'push');
            app.AdjustButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdjustButton_2Pushed, true);
            app.AdjustButton_2.Text = 'Adjust';
            app.DoneButton_4 = uibutton(app.AdjustthresholdPanel, 'push');
            app.DoneButton_4.ButtonPushedFcn = createCallbackFcn(app, @DoneButton_4Pushed, true);
            app.DoneButton_4.Text = 'Done';
            app.Slider_5 = uislider(app.AdjustthresholdPanel);
            app.Slider_5.Limits = [0 1];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.Value = 0.5;
            app.ThresholdLabel = uilabel(app.AdjustthresholdPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.AdjustthresholdPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.ValueDisplayFormat = '%.2f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.5;
            app.BackButton_7 = uibutton(app.AdjustthresholdPanel, 'push');
            app.BackButton_7.ButtonPushedFcn = createCallbackFcn(app, @BackButton_7Pushed, true);
            app.BackButton_7.Text = 'Back';
            app.EditnucleisegmentationPanel = uipanel(app.LeftPanel);
            app.EditnucleisegmentationPanel.Title = 'Edit nuclei segmentation';
            app.EditnucleisegmentationPanel.Visible = 'off';
            app.RemovenucleiButton = uibutton(app.EditnucleisegmentationPanel, 'push');
            app.RemovenucleiButton.ButtonPushedFcn = createCallbackFcn(app, @RemovenucleiButtonPushed, true);
            app.RemovenucleiButton.Text = 'Remove nuclei';
            app.AddnucleiButton = uibutton(app.EditnucleisegmentationPanel, 'push');
            app.AddnucleiButton.ButtonPushedFcn = createCallbackFcn(app, @AddnucleiButtonPushed, true);
            app.AddnucleiButton.Text = 'Add nuclei';
            app.ResetButton = uibutton(app.EditnucleisegmentationPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveexitButton_2 = uibutton(app.EditnucleisegmentationPanel, 'push');
            app.SaveexitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveexitButton_2Pushed, true);
            app.SaveexitButton_2.Text = 'Save & exit';
            app.RedoanalysisButton = uibutton(app.EditnucleisegmentationPanel, 'push');
            app.RedoanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RedoanalysisButtonPushed, true);
            app.RedoanalysisButton.Text = 'Redo analysis';
            app.BackButton_6 = uibutton(app.EditnucleisegmentationPanel, 'push');
            app.BackButton_6.ButtonPushedFcn = createCallbackFcn(app, @BackButton_6Pushed, true);
            app.BackButton_6.Text = 'Back';
            app.Switch = uiswitch(app.EditnucleisegmentationPanel, 'slider');
            app.Switch.Items = {'Left', 'Right'};
            app.Switch.Value = 'Left';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Select function or file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Myotube_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end